classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ClearButton      matlab.ui.control.Button
        ResultLabel      matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        ExpressionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        recording = false
        expression = ""
        lastToken = ""
    end
    
    methods (Access = private)
        
        function out = mockSpeech(app)
            persistent idx sequence
            
            if isempty(idx)
                idx = 1;
                sequence = {'1', '2', '+', '3', '='};   % fixed sequence
            end
            
            % Return next element
            out = sequence{idx};
            
            % Wrap or clamp at end
            if idx < numel(sequence)
                idx = idx + 1;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonPushed(app, event)
            if app.recording
                app.Button.Icon = "mic.svg";
                app.recording = false;

                if app.expression == ""
                    app.ExpressionLabel.Text = "Click the Record button to start recording...";
                end
            else
                app.Button.Icon = "stop.svg";
                app.recording = true;

                if app.expression == ""
                    app.ExpressionLabel.Text = "...";
                end

                app.lastToken = app.mockSpeech();

                if (app.lastToken ~= "=")
                    app.expression = strcat(app.expression, app.lastToken);
                    app.ExpressionLabel.Text = app.expression;
                else
                    app.ResultLabel.Text = strcat('= ', string(eval(app.expression)));
                end

            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.expression = "";
            app.ExpressionLabel.Text = "Click the Record button to start recording...";
            app.recording = true;
            ButtonPushed(app,null);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExpressionLabel = uilabel(app.UIFigure);
            app.ExpressionLabel.Text = 'Click the Record button to start recording...';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'mic.svg');
            app.Button.Text = '';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end